classdef VC8control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VC8ControlsoftwareUIFigure     matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        OpensequenceMenu               matlab.ui.container.Menu
        SavesequenceasMenu             matlab.ui.container.Menu
        ValvecontrolPanel              matlab.ui.container.Panel
        SwitchButton_4                 matlab.ui.control.Button
        SwitchButton_3                 matlab.ui.control.Button
        SwitchButton_2                 matlab.ui.control.Button
        SwitchButton_1                 matlab.ui.control.Button
        SwitchButton_8                 matlab.ui.control.Button
        SwitchButton_7                 matlab.ui.control.Button
        SwitchButton_6                 matlab.ui.control.Button
        SwitchButton_5                 matlab.ui.control.Button
        Label_8                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label_1                        matlab.ui.control.Label
        Lamp_16                        matlab.ui.control.Lamp
        Lamp_15                        matlab.ui.control.Lamp
        Lamp_14                        matlab.ui.control.Lamp
        Lamp_13                        matlab.ui.control.Lamp
        Lamp_12                        matlab.ui.control.Lamp
        Lamp_11                        matlab.ui.control.Lamp
        Lamp_10                        matlab.ui.control.Lamp
        Lamp_9                         matlab.ui.control.Lamp
        Lamp_8                         matlab.ui.control.Lamp
        Lamp_7                         matlab.ui.control.Lamp
        Lamp_6                         matlab.ui.control.Lamp
        Lamp_5                         matlab.ui.control.Lamp
        Lamp_4                         matlab.ui.control.Lamp
        Lamp_3                         matlab.ui.control.Lamp
        Lamp_2                         matlab.ui.control.Lamp
        Lamp_1                         matlab.ui.control.Lamp
        TabGroup                       matlab.ui.container.TabGroup
        ConnectTab                     matlab.ui.container.Tab
        AvailableCOMportsDropDown      matlab.ui.control.DropDown
        AvaiableCOMportsDropDownLabel  matlab.ui.control.Label
        ResetconnectionButton          matlab.ui.control.Button
        ConnectButton                  matlab.ui.control.Button
        CircuitDesignTab               matlab.ui.container.Tab
        SetvalvestateDropDown          matlab.ui.control.DropDown
        SetvalvestateDropDownLabel     matlab.ui.control.Label
        Image_3                        matlab.ui.control.Image
        Image_2                        matlab.ui.control.Image
        SelectDropDown                 matlab.ui.control.DropDown
        SelectDropDownLabel            matlab.ui.control.Label
        Image_1                        matlab.ui.control.Image
        ProgramTab                     matlab.ui.container.Tab
        ResetButton                    matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        NewlineButton                  matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        serial % Description
        data
    end
    
    methods (Access = private)
        
        function switch_valve(app,value)
            s=app.serial;

            VC8_set(s,value);
            displayValves(app,value)
        end


        function  set_valve_data(app)
            value = app.SelectDropDown.Value;
            
            pix=find(matches(app.SelectDropDown.Items,value));
         
            value = app.SelectDropDown.Value;
            value=str2num(value(1));
             
            for i=1:3
                app.(['Image_' num2str(i)]).Enable='off';
            end
            app.(['Image_' num2str(pix)]).Enable='on';
            
            s=app.serial;
            
            if strcmp(class(s),'internal.Serialport')
            [out letters codes]=VC8_get_group_code(s,value,{'A2'});
            app.SetvalvestateDropDown.Items=letters;
            app.SetvalvestateDropDown.Value='A2';
            app.SetvalvestateDropDownValueChanged;
            end
         
            
            str={};
            st=64;
            
    
            app.UITable.ColumnName={};
            app.UITable.ColumnFormat={};
  
            data={};
            
            type={'stairs','ramp'};
            
            col=cell(1,3*value);
            
            for i=1:value
              
                str=[str [ char(st+i) ' - time '], [char(st+i) ' - value '], [ char(st+i) ' - type']];
                
                pix= find(contains(letters,char(st+i)));
               
                valstr=cell(1,numel(pix));
                
                for j=1:numel(pix)
                  valstr{j}=num2str(j);
                end
                
                col{3*i-1}=valstr;
                col{3*i}={'stairs','ramp'};
                    
                data{1,3*i-2}=[];
                data{1,3*i-1}=valstr{1};
                data{1,3*i}='stairs';
            end
            
         
         %   for i=2:10
         %           data(i,:)=data(1,:);
         %   end

             app.UITable.Data=data; 
             app.UITable.ColumnFormat=col;
             app.UITable.ColumnName=str;
              
             app.data=data;
             displayTimer(app);
        end
    end
    
    methods (Access = public)
        
        function  displayTimer(app)
            data=app.data;
          
           [time, position, type, letter]=VC8_format_data(data);
            
            % plot curve for each group 
          
            % find timer if any 
            tim=timerfind('Name','valvetimer');
            
            if numel(tim)
            currentime= tim.UserData.currentime;
            end
            
          %  time,position,type,letter
          cmap=lines(numel(time));
          str={};
          isplotted=0;
          cla(app.UIAxes)
     
           hold(app.UIAxes,'on')
           cc=1;
           
            for i=1:numel(time)
                
                if numel(time{i})==0
                    continue
                else
                   isplotted=1; 
                end
                
                t=time{i};
                p=position{i};
                tt=type{i};
                
                t2=[];
                p2=[];
                
                for j=1:numel(t)-1
                    
                   if tt(j)==0 % stairs
                       [x,y] = stairs([t(j) t(j+1)],[p(j) p(j+1)]);
                       t2=[t2 x(1:end)'];
                       p2=[p2 y(1:end)']; 
                   else
                      t2=[t2 t(j)];
                      p2=[p2 p(j)]; 
                   end
                end
                
                t2=[t2 t(end)];
                p2=[p2 p(end)];
               
                plot(app.UIAxes,t2,p2,'LineWidth',2,'Color',cmap(i,:)); 
                str{cc}=letter{i};
                cc=cc+1;
                
                if numel(tim)
                       
                pix=find( t2>currentime,1,'first');
                if numel(pix)==0 % time is past the last sequence , so go for the last valve state
                    curpos=p2(end);
                    currentime=t2(end);
                else
                    if pix==1
                         curpos=p2(1);
                         currentime=t2(1);    
                    else
                    
                    val1=p2(pix-1);
                     val2=p2(pix);
       
                     t1=t2(pix-1);
                     t2=t2(pix);
       
                     r=(currentime-t1)./(t2-t1);
                     curpos=val1+r*(val2-val1);
                    end
                end
                

                 plot(app.UIAxes,currentime,curpos,'LineWidth',20,'Color',cmap(i,:),'LineStyle','none','Marker','.','MarkerSize',20); 
                str{cc}=[letter{i} ' - current position'];
                cc=cc+1;
                end
                
            end
            
            if isplotted
            legend(app.UIAxes,str);
            ylim(app.UIAxes,'auto');
            xlim(app.UIAxes,'auto');
            end
            hold(app.UIAxes,'off')
        end


        function displayValves(app,value)
           
              for i=1:size(value,1) % updates display
                  if value(i,2)==1
                       app.(['Lamp_' num2str(2*value(i))]).Color=[0 1 0];
                       app.(['Lamp_' num2str(2*value(i)-1)]).Color=[0 0 0];
                  else
                      app.(['Lamp_' num2str(2*value(i)-1)]).Color=[1 0 0];
                      app.(['Lamp_' num2str(2*value(i))]).Color=[0 0 0];
                   end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % set up display
            for i=1:8
                app.(['Lamp_' num2str(2*i-1)]).Color=[0.3 0.3 0.3];
                app.(['Lamp_' num2str(2*i)]).Color=[0.3 0.3 0.3];
                app.(['SwitchButton_' num2str(i)]).Enable='off';
            end
            
             app.(['Image_' num2str(1)]).Enable='on';
            
            %gather COM ports
            
            %ports = serialportlist;
            freeports = serialportlist("available");
    
            app.AvailableCOMportsDropDown.Items=freeports;
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
           comport=app.AvailableCOMportsDropDown.Value;
            
           s=VC8_connect(comport,app);
            
          
           if numel(s)
            app.serial=s;
           else
              uialert(app.VC8ControlsoftwareUIFigure,'Could not connect','Error'); 
           end
           
         %  pause(0.1);
         %  test=VC8_get(s)
         pause(1);
         test=VC8_get(s);
           
           if numel(test)==0 % time out
              uialert(app.VC8ControlsoftwareUIFigure,'Could not connect to device ! Check COM Port...','Error')
              delete(s);
              app.serial=[];
             return;  
           end

             for i=1:8
                app.(['SwitchButton_' num2str(i)]).Enable='on';
                app.(['Lamp_' num2str(2*i)]).Color=[0 1 0];
                app.(['Lamp_' num2str(2*i-1)]).Color=[0 0 0];
            %  aa=  [1:8; ones(1,8)]'
               % VC8_set(s,[1:8; zeros(1,8)]')
             end
             
           pause(0.2);
           set_valve_data(app);
           
            % 
           %  VC8_set(s,[1 0])
        end

        % Value changed function: AvailableCOMportsDropDown
        function AvailableCOMportsDropDownValueChanged(app, event)
            value = app.AvailableCOMportsDropDown.Value;
            
        end

        % Button pushed function: ResetconnectionButton
        function ResetconnectionButtonPushed(app, event)
            VC8_disconnect(app.serial);
            
            for i=1:8
                app.(['Lamp_' num2str(2*i-1)]).Color=[0.3 0.3 0.3];
                app.(['Lamp_' num2str(2*i)]).Color=[0.3 0.3 0.3];
                app.(['SwitchButton_' num2str(i)]).Enable='off';
            end
            
        end

        % Button pushed function: SwitchButton_1
        function SwitchButton_1Pushed(app, event)
            s=app.serial;
            but=1;
            
            if app.(['Lamp_' num2str(2*but-1)]).Color==[1 0 0]
             val=1;
            else
             val=0;   
            end
           
           switch_valve(app,[but val]);
            
        end

        % Button pushed function: SwitchButton_2
        function SwitchButton_2Pushed(app, event)
            s=app.serial;
            but=2;
            
            if app.(['Lamp_' num2str(2*but-1)]).Color==[1 0 0]
             val=1;
            else
             val=0;   
            end
           
           switch_valve(app,[but val]);
        end

        % Button pushed function: SwitchButton_3
        function SwitchButton_3Pushed(app, event)
             s=app.serial;
            but=3;
            
            if app.(['Lamp_' num2str(2*but-1)]).Color==[1 0 0]
             val=1;
            else
             val=0;   
            end
           
           switch_valve(app,[but val]);
        end

        % Button pushed function: SwitchButton_4
        function SwitchButton_4Pushed(app, event)
             s=app.serial;
            but=4;
            
            if app.(['Lamp_' num2str(2*but-1)]).Color==[1 0 0]
             val=1;
            else
             val=0;   
            end
           
           switch_valve(app,[but val]);
        end

        % Button pushed function: SwitchButton_5
        function SwitchButton_5Pushed(app, event)
             s=app.serial;
            but=5;
            
            if app.(['Lamp_' num2str(2*but-1)]).Color==[1 0 0]
             val=1;
            else
             val=0;   
            end
           
           switch_valve(app,[but val]);
        end

        % Button pushed function: SwitchButton_6
        function SwitchButton_6Pushed(app, event)
             s=app.serial;
            but=6;
            
            if app.(['Lamp_' num2str(2*but-1)]).Color==[1 0 0]
             val=1;
            else
             val=0;   
            end
           
           switch_valve(app,[but val]);
        end

        % Button pushed function: SwitchButton_7
        function SwitchButton_7Pushed(app, event)
             s=app.serial;
            but=7;
            
            if app.(['Lamp_' num2str(2*but-1)]).Color==[1 0 0]
             val=1;
            else
             val=0;   
            end
           
           switch_valve(app,[but val]);
        end

        % Button pushed function: SwitchButton_8
        function SwitchButton_8Pushed(app, event)
             s=app.serial;
            but=8;
            
            if app.(['Lamp_' num2str(2*but-1)]).Color==[1 0 0]
             val=1;
            else
             val=0;   
            end
           
           switch_valve(app,[but val]);
        end

        % Close request function: VC8ControlsoftwareUIFigure
        function VC8ControlsoftwareUIFigureCloseRequest(app, event)

           if numel(app.serial)
               delete(app.serial);
           end
            delete(app)
        end

        % Value changed function: SelectDropDown
        function SelectDropDownValueChanged(app, event)
            cla(app.UIAxes)
            set_valve_data(app)
        end

        % Value changed function: SetvalvestateDropDown
        function SetvalvestateDropDownValueChanged(app, event)
            str = app.SetvalvestateDropDown.Value;
            
             value = app.SelectDropDown.Value;
             value=str2num(value(1));
             
           % pix=find(matches(app.SelectDropDown.Items,value))
            s=app.serial;
            out=VC8_group_set(s,value,{str});
           
            for i=1:size(out,1) % updates display
                  if out(i,2)==1
                       app.(['Lamp_' num2str(2*out(i))]).Color=[0 1 0];
                       app.(['Lamp_' num2str(2*out(i)-1)]).Color=[0 0 0];
                  else
                      app.(['Lamp_' num2str(2*out(i)-1)]).Color=[1 0 0];
                      app.(['Lamp_' num2str(2*out(i))]).Color=[0 0 0];
                   end
            end
            
            
        end

        % Menu selected function: FileMenu
        function FileMenuSelected(app, event)
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            
            data=app.UITable.Data;
            
            if numel(data)==0 
                if size(data,1)>1
                data=data(1:end-1,:);
                end
            end
            
%             if numel(newData)==0 & indices(2)==1
%                data=data(1:indices(1)-1,:);
%             end
            
%             if indices(1)==size(data,1) & indices(2)==1
%                 data(end+1,:)=data(end,:);
%             end
            
           % class(data)
           % size(data)
           % size(app.UITable.Data)
            % app.UITable.Data={};
           % event.Source.Data=data;
            app.UITable.Data=data;
            app.data=data;
            
            displayTimer(app)
        end

        % Button pushed function: NewlineButton
        function NewlineButtonPushed(app, event)
              data=app.UITable.Data;
               data(end+1,:)=data(end,:);
               for i=1:size(data,2)
                   if mod(i,3)==1
               data{end,i}=[];
                   end
               end
               
                app.UITable.Data=data;
                app.data=data;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            if strcmp(app.StartButton.Text,'Start')
                app.StartButton.Text='Stop';
                
             %   t=timerfind('Name','valvetimer');
              %  if numel(t)
                    
                    
            %    end
             %   
                appdata=[];
                appdata.start=clock; 
                appdata.app=app;
                
                [time, position, type, letter]=VC8_format_data(app.data);
                appdata.time=time;
                appdata.position=position;
                appdata.type=type; 
                appdata.letter=letter;
                appdata.currentime=0;
                
                for i=1:numel(time)
                appdata.avg{i}=[]; % average valve state used for ramps
                end
                
                t=timer('ExecutionMode','fixedRate','Period',0.2,'Name','valvetimer','UserData',appdata);
                t.TimerFcn=@VC8_timer;
                start(t);
            else
                app.StartButton.Text='Start'; 
                
                t=timerfind('Name','valvetimer');
                
                stop(t)
                delete(t);
                
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
%              t=timerfind('Name','valvetimer');
%              if numel(t)
%              stop(t)
%              delete(t);
%              displayTime(app);
%              end
        end

        % Menu selected function: OpensequenceMenu
        function OpensequenceMenuSelected(app, event)
            [file path]=uigetfile('*.mat','Select sequence file name and destination');
               if numel(file)==0
                return;
                else
                load(fullfile(path,file),'data');
               end


               app.SelectDropDown.Value=[num2str(size(data,2)/3) ' groups'];
               app.SelectDropDownValueChanged;
               
               app.data=data;
               app.UITable.Data=data;
              
               displayTimer(app);
        end

        % Menu selected function: SavesequenceasMenu
        function SavesequenceasMenuSelected(app, event)
            data=app.data; 
            
            [file path]=uiputfile('*.mat','Select file name and destination','mysequence.mat')
            
            if numel(file)==0
                return;
            else
                save(fullfile(path,file),'data');
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VC8ControlsoftwareUIFigure = uifigure('Visible', 'off');
            app.VC8ControlsoftwareUIFigure.Name = 'VC-8 Control software';
            app.VC8ControlsoftwareUIFigure.CloseRequestFcn = createCallbackFcn(app, @VC8ControlsoftwareUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.VC8ControlsoftwareUIFigure);
            app.FileMenu.MenuSelectedFcn = createCallbackFcn(app, @FileMenuSelected, true);
            app.FileMenu.Text = 'File';
            app.OpensequenceMenu = uimenu(app.FileMenu);
            app.OpensequenceMenu.MenuSelectedFcn = createCallbackFcn(app, @OpensequenceMenuSelected, true);
            app.OpensequenceMenu.Text = 'Open sequence...';
            app.SavesequenceasMenu = uimenu(app.FileMenu);
            app.SavesequenceasMenu.MenuSelectedFcn = createCallbackFcn(app, @SavesequenceasMenuSelected, true);
            app.SavesequenceasMenu.Text = 'Save sequence as...';
            app.TabGroup = uitabgroup(app.VC8ControlsoftwareUIFigure);
            app.ConnectTab = uitab(app.TabGroup);
            app.ConnectTab.Title = 'Connect';
            app.ConnectButton = uibutton(app.ConnectTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ResetconnectionButton = uibutton(app.ConnectTab, 'push');
            app.ResetconnectionButton.ButtonPushedFcn = createCallbackFcn(app, @ResetconnectionButtonPushed, true);
            app.ResetconnectionButton.Text = 'Reset connection';
            app.AvaiableCOMportsDropDownLabel = uilabel(app.ConnectTab);
            app.AvaiableCOMportsDropDownLabel.Text = 'Avaiable COM ports:';
            app.AvailableCOMportsDropDown = uidropdown(app.ConnectTab);
            app.AvailableCOMportsDropDown.ValueChangedFcn = createCallbackFcn(app, @AvailableCOMportsDropDownValueChanged, true);
            app.CircuitDesignTab = uitab(app.TabGroup);
            app.CircuitDesignTab.Title = 'Circuit Design';
            app.Image_1 = uiimage(app.CircuitDesignTab);
            app.Image_1.Enable = 'off';
            app.Image_1.ImageSource = 'valves8.png';
            app.SelectDropDownLabel = uilabel(app.CircuitDesignTab);
            app.SelectDropDownLabel.Text = 'Select:';
            app.SelectDropDown = uidropdown(app.CircuitDesignTab);
            app.SelectDropDown.Items = {'8 groups', '4 groups', '2 groups', ''};
            app.SelectDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDropDownValueChanged, true);
            app.SelectDropDown.Value = '8 groups';
            app.Image_2 = uiimage(app.CircuitDesignTab);
            app.Image_2.Enable = 'off';
            app.Image_2.ImageSource = 'valves4.png';
            app.Image_3 = uiimage(app.CircuitDesignTab);
            app.Image_3.Enable = 'off';
            app.Image_3.ImageSource = 'valves2.png';
            app.SetvalvestateDropDownLabel = uilabel(app.CircuitDesignTab);
            app.SetvalvestateDropDownLabel.Text = 'Set valve state:';
            app.SetvalvestateDropDown = uidropdown(app.CircuitDesignTab);
            app.SetvalvestateDropDown.Items = {};
            app.SetvalvestateDropDown.ValueChangedFcn = createCallbackFcn(app, @SetvalvestateDropDownValueChanged, true);
            app.SetvalvestateDropDown.Value = {};
            app.ProgramTab = uitab(app.TabGroup);
            app.ProgramTab.Title = 'Program';
            app.UIAxes = uiaxes(app.ProgramTab);
            xlabel(app.UIAxes, 'Time (min)')
            ylabel(app.UIAxes, 'Valve State')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YTick = [1 2 3 4 5 6 7 8];
            app.UIAxes.YTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'};
            app.UITable = uitable(app.ProgramTab);
            app.UITable.ColumnName = {'Column 1'; 'Column2'};
            app.UITable.RowName = {''; ''};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.NewlineButton = uibutton(app.ProgramTab, 'push');
            app.NewlineButton.ButtonPushedFcn = createCallbackFcn(app, @NewlineButtonPushed, true);
            app.NewlineButton.Text = 'New line';
            app.StartButton = uibutton(app.ProgramTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.ProgramTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Enable = 'off';
            app.ResetButton.Text = 'Reset';
            app.ValvecontrolPanel = uipanel(app.VC8ControlsoftwareUIFigure);
            app.ValvecontrolPanel.Title = 'Valve control';
            app.Lamp_1 = uilamp(app.ValvecontrolPanel);
            app.Lamp_2 = uilamp(app.ValvecontrolPanel);
            app.Lamp_3 = uilamp(app.ValvecontrolPanel);
            app.Lamp_4 = uilamp(app.ValvecontrolPanel);
            app.Lamp_5 = uilamp(app.ValvecontrolPanel);
            app.Lamp_6 = uilamp(app.ValvecontrolPanel);
            app.Lamp_7 = uilamp(app.ValvecontrolPanel);
            app.Lamp_8 = uilamp(app.ValvecontrolPanel);
            app.Lamp_9 = uilamp(app.ValvecontrolPanel);
            app.Lamp_10 = uilamp(app.ValvecontrolPanel);
            app.Lamp_11 = uilamp(app.ValvecontrolPanel);
            app.Lamp_12 = uilamp(app.ValvecontrolPanel);
            app.Lamp_13 = uilamp(app.ValvecontrolPanel);
            app.Lamp_14 = uilamp(app.ValvecontrolPanel);
            app.Lamp_15 = uilamp(app.ValvecontrolPanel);
            app.Lamp_16 = uilamp(app.ValvecontrolPanel);
            app.Label_1 = uilabel(app.ValvecontrolPanel);
            app.Label_1.Text = '1';
            app.Label_2 = uilabel(app.ValvecontrolPanel);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.ValvecontrolPanel);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.ValvecontrolPanel);
            app.Label_4.Text = '4';
            app.Label_5 = uilabel(app.ValvecontrolPanel);
            app.Label_5.Text = '5';
            app.Label_6 = uilabel(app.ValvecontrolPanel);
            app.Label_6.Text = '6';
            app.Label_7 = uilabel(app.ValvecontrolPanel);
            app.Label_7.Text = '7';
            app.Label_8 = uilabel(app.ValvecontrolPanel);
            app.Label_8.Text = '8';
            app.SwitchButton_5 = uibutton(app.ValvecontrolPanel, 'push');
            app.SwitchButton_5.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_5Pushed, true);
            app.SwitchButton_5.Text = 'Switch';
            app.SwitchButton_6 = uibutton(app.ValvecontrolPanel, 'push');
            app.SwitchButton_6.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_6Pushed, true);
            app.SwitchButton_6.Text = 'Switch';
            app.SwitchButton_7 = uibutton(app.ValvecontrolPanel, 'push');
            app.SwitchButton_7.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_7Pushed, true);
            app.SwitchButton_7.Text = 'Switch';
            app.SwitchButton_8 = uibutton(app.ValvecontrolPanel, 'push');
            app.SwitchButton_8.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_8Pushed, true);
            app.SwitchButton_8.Text = 'Switch';
            app.SwitchButton_1 = uibutton(app.ValvecontrolPanel, 'push');
            app.SwitchButton_1.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_1Pushed, true);
            app.SwitchButton_1.Text = 'Switch';
            app.SwitchButton_2 = uibutton(app.ValvecontrolPanel, 'push');
            app.SwitchButton_2.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_2Pushed, true);
            app.SwitchButton_2.Text = 'Switch';
            app.SwitchButton_3 = uibutton(app.ValvecontrolPanel, 'push');
            app.SwitchButton_3.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_3Pushed, true);
            app.SwitchButton_3.Text = 'Switch';
            app.SwitchButton_4 = uibutton(app.ValvecontrolPanel, 'push');
            app.SwitchButton_4.ButtonPushedFcn = createCallbackFcn(app, @SwitchButton_4Pushed, true);
            app.SwitchButton_4.Text = 'Switch';
            % Show the figure after all components are created
            app.VC8ControlsoftwareUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VC8control

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VC8ControlsoftwareUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VC8ControlsoftwareUIFigure)
        end
    end
end