classdef pump_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DirectionSwitchLabel     matlab.ui.control.Label
        DirectionSwitch          matlab.ui.control.Switch
        Switch2                  matlab.ui.control.ToggleSwitch
        VariablesPanel           matlab.ui.container.Panel
        NormalflowSpinnerLabel   matlab.ui.control.Label
        NormalflowSpinner        matlab.ui.control.Spinner
        PushingflowSpinnerLabel  matlab.ui.control.Label
        PushingflowSpinner       matlab.ui.control.Spinner
        FramerateSpinnerLabel    matlab.ui.control.Label
        FramerateSpinner         matlab.ui.control.Spinner
        DurationSpinnerLabel     matlab.ui.control.Label
        DurationSpinner          matlab.ui.control.Spinner
        PortDropDownLabel        matlab.ui.control.Label
        PortDropDown             matlab.ui.control.DropDown
        EditField                matlab.ui.control.EditField
        Button                   matlab.ui.control.StateButton
        EditField_2              matlab.ui.control.EditField
    end

    
    properties (Access = public)
        DESCRIPTION='Ismatec IPC';
        NAME='Pump1';
        BAUD_RATE=9600;
        TERMINATOR=char(13);
        
        GET_DEBIT='1!';
        GET_ID='1+';
        SET_CW_DIRECTION='1J';
        SET_CCW_DIRECTION='1K';
        SET_DEBIT='1!';
        SET_ID='1+00';
        START='1H';
        STOP='1I';

    end
    
    methods (Access = private)
        
        function connect(app)
                s = serialport(app.NAME,app.BAUD_RATE);
                s.Terminator=app.TERMINATOR;
                writeline(s,"1I")
                out = readline(s);
                
                if out == "*"
                    app.EditField.value= "Connected !";
                elseif out~="*"
                    app.EditField.value=out;
                end
        end
        
        function disconnect(app)
               clear s;
               app.EditField.value= "Not connected !";
        end
        
        function runLoop(app)
                tic;
                elapsed=toc;
                while elapsed<app.DurationSpinner.Value*60000
                    writeline(s,sprintf(app.SET_DEBIT,normalFlow))
                    pause(0.2*app.FramerateSpinner.Value)
                    writeline(s,sprintf(app.SET_DEBIT,pushingFlow))
                    pause(0.8*app.FramerateSpinner.Value)
                    elapsed=toc;
                end
        end
        
        function setDirection(app)
            if direction=="-"
                writeline(s,app.SET_CCW_DIRECTION)
            elseif direction=="+"
                writeline(s,app.SET_CW_DIRECTION)
            end
        end


        function switchOnOff(app)
            if app.Switch2.Value==On
               writeline(s,app.START);
            elseif app.Switch2.Value==Off
               writeline(s,app.STOP);
            end
        end
        
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function DurationSpinnerValueChanging(app, event)

            
        end

        % Callback function
        function DurationSpinnerValueChanged(app, event)
            app.DurationSpinner.Value;
            
        end

        % Value changed function: FramerateSpinner
        function FramerateSpinnerValueChanged(app, event)
            app.FramerateSpinner.Value;
            
        end

        % Value changed function: PushingflowSpinner
        function PushingflowSpinnerValueChanged(app, event)
            app.PushingflowSpinner.Value;
            
        end

        % Value changed function: NormalflowSpinner
        function NormalflowSpinnerValueChanged(app, event)
            app.NormalflowSpinner.Value;
            
        end

        % Value changed function: DirectionSwitch
        function DirectionSwitchValueChanged(app, event)
            app.DirectionSwitch.Value;
            setDirection(app);
        end

        % Value changed function: PortDropDown
        function PortDropDownValueChanged(app, event)
            app.PortDropDown.Value;
            
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            switchOnOff(app);
        end

        % Value changing function: EditField
        function EditFieldValueChanging(app, event)
         
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            if app.Button.Value==true
                connect(app)
            elseif app.Button.Value==false
                disconnect(app)
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.DirectionSwitchLabel = uilabel(app.UIFigure);
            app.DirectionSwitchLabel.Text = 'Direction';
            app.DirectionSwitch = uiswitch(app.UIFigure, 'slider');
            app.DirectionSwitch.Items = {'-', '+'};
            app.DirectionSwitch.ValueChangedFcn = createCallbackFcn(app, @DirectionSwitchValueChanged, true);
            app.DirectionSwitch.Value = '-';
            app.Switch2 = uiswitch(app.UIFigure, 'toggle');
            app.Switch2.Items = {'Off', 'Go'};
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.VariablesPanel = uipanel(app.UIFigure);
            app.VariablesPanel.Title = 'Variables';
            app.NormalflowSpinnerLabel = uilabel(app.VariablesPanel);
            app.NormalflowSpinnerLabel.Text = ' Normal flow';
            app.NormalflowSpinner = uispinner(app.VariablesPanel);
            app.NormalflowSpinner.ValueChangedFcn = createCallbackFcn(app, @NormalflowSpinnerValueChanged, true);
            app.PushingflowSpinnerLabel = uilabel(app.VariablesPanel);
            app.PushingflowSpinnerLabel.Text = 'Pushing flow';
            app.PushingflowSpinner = uispinner(app.VariablesPanel);
            app.PushingflowSpinner.ValueChangedFcn = createCallbackFcn(app, @PushingflowSpinnerValueChanged, true);
            app.FramerateSpinnerLabel = uilabel(app.VariablesPanel);
            app.FramerateSpinnerLabel.Text = 'Framerate';
            app.FramerateSpinner = uispinner(app.VariablesPanel);
            app.FramerateSpinner.ValueChangedFcn = createCallbackFcn(app, @FramerateSpinnerValueChanged, true);
            app.DurationSpinnerLabel = uilabel(app.VariablesPanel);
            app.DurationSpinnerLabel.Text = 'Duration';
            app.DurationSpinner = uispinner(app.VariablesPanel);
            app.PortDropDownLabel = uilabel(app.UIFigure);
            app.PortDropDownLabel.Text = 'Port';
            app.PortDropDown = uidropdown(app.UIFigure);
            app.PortDropDown.Items = {'COM1', 'COM2', 'COM3', 'Option 4'};
            app.PortDropDown.ValueChangedFcn = createCallbackFcn(app, @PortDropDownValueChanged, true);
            app.PortDropDown.Value = 'COM1';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @EditFieldValueChanging, true);
            app.EditField.Editable = 'off';
            app.EditField.Value = 'Not connected';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Button';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pump_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end